classdef breathFreqGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BreathParams                  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        LoadButton                    matlab.ui.control.Button
        RecordButton                  matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        TimeOfRecordLabel             matlab.ui.control.Label
        BreathsPerMinuteTempLabel     matlab.ui.control.Label
        TimeOfRecordTxt               matlab.ui.control.Label
        BreathsPerMinuteTempTxt       matlab.ui.control.Label
        BreathsPerMinuteOverallLabel  matlab.ui.control.Label
        BreathsPerMinuteOverallTxt    matlab.ui.control.Label
        BreathTypeLastLabel           matlab.ui.control.Label
        BreathTypeLastTxt             matlab.ui.control.Label
        BreathTypeOverallLabel        matlab.ui.control.Label
        BreathTypeOverallTxt          matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        AxesFilter                    matlab.ui.control.UIAxes
        AxesRaw                       matlab.ui.control.UIAxes
        AxesEnvelope                  matlab.ui.control.UIAxes
        BreathAll                     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        samplingFreq = 44100;
        pcm = 16;
        channels = 1;
        recorder = audiorecorder(44100, 16, 1);
        overallBPM = 0;
        overallSOB = 0;
    end
    
    methods (Access = private)
               
        function tVector = timeVector(app, data, samplingFreq)
            t = size(data, 1)/samplingFreq;
            tVector = 0:1/samplingFreq:t - 1/samplingFreq;
        end
        
        function envelope = signalEnveloper(app, samplingFrequency, data)
            [b, a] = butter(3, 1/(samplingFrequency/2.15));
            dataAbs = abs(data);
            envelope = filter(b, a, dataAbs);
        end
        
        function [breathsPerMinute, strengthOfBreath] =  processData(app, data, samplingFrequency)
            [downsampledData, downedSamplingFreq, ds] = dataDownsampler(app, data, samplingFrequency);
            tVector = timeVector(app, downsampledData, downedSamplingFreq);
            plot(app.AxesRaw, tVector, downsampledData(:, 1));
            
            filteredSignal = signalFilter(app, downsampledData, downedSamplingFreq);
            envelope = signalEnveloper(app, downedSamplingFreq, filteredSignal);
            [peaks, loc] = findpeaks(envelope, 'MinPeakDistance', 5*ds);
            normEnvelope = envelopeNormalizer(app, peaks, envelope);
            plot(app.AxesFilter, tVector, envelope(:, 1));
            
            [normPeaks, normLoc] = findpeaks(normEnvelope, 'MinPeakHeight', 0.37, 'MinPeakDistance', 5*ds);
            plot(app.AxesEnvelope, tVector, normEnvelope(:, 1), tVector(normLoc), normPeaks, 'rx', 'MarkerSize', 8);
            
            if (normPeaks(end) >= 0.7)
                app.BreathTypeLastTxt.Text = 'Hard breath';
            elseif (normPeaks(end) < 0.7 && normPeaks(end) >= 0.5)
                app.BreathTypeLastTxt.Text = 'Soft breath';
            else
                app.BreathTypeLastTxt.Text = 'Mild breath';
            end
            
            breathsPerMinute = size(normPeaks);
            strengthOfBreath = sum(normPeaks)./size(normPeaks);
        end
        
        function audioRecorder(app)
            lastSampleIndex = 0;
            set(app.recorder, 'TimerPeriod', 1, 'TimerFcn', @audioTimerCallback);
            drawnow;
            record(app.recorder);
            
            function audioTimerCallback(hObject, ~)
                samples  = getaudiodata(hObject);
                time = size(samples, 1)/app.samplingFreq;
                app.TimeOfRecordTxt.Text = datestr(seconds(time), 'hh:MM:ss');
                
                if length(samples) < app.samplingFreq * 60
                    return;
                end
                
                data = samples(lastSampleIndex + 1:lastSampleIndex + app.samplingFreq * 60);
                [breathsPerMinute, strengthOfBreath] = processData(app, data, app.samplingFreq);
                
                app.overallBPM = app.overallBPM + breathsPerMinute(1);
                app.BreathsPerMinuteTempTxt.Text = num2str(breathsPerMinute(1));
                app.overallSOB = app.overallSOB + strengthOfBreath(1);
                lastSampleIndex = lastSampleIndex + app.samplingFreq;
            end
        end
        
        function normEnvelope = envelopeNormalizer(app, peaks, envelope)
            highestPeak = peaks(1);
            for i = 1:size(peaks, 1)
                if highestPeak < peaks(i)
                    highestPeak = peaks(i);
                end
            end
            normEnvelope = envelope/highestPeak;
        end
        
        function [downsampledData, downedSamplingFreq, ds] = dataDownsampler(app, data, samplingFrequency)
            ds = 1;
            if samplingFrequency > 10000
                ds = 100;
            elseif (samplingFrequency > 1000) && (samplingFrequency < 10000)
                ds = 10;
            end
            downedSamplingFreq = samplingFrequency/ds;
            downsampledData = downsample(data, ds);
        end
        
        function filteredSignal = signalFilter(app, data, samplingFreq)
            nyquistFreq = samplingFreq/2;
            passbandFrequency = [100 210]/nyquistFreq;
            stopbandFrequency = [99 211]/nyquistFreq;
            passbandRipple = 1;
            stopbandRipple = 150;
            [n, Ws] = cheb2ord(passbandFrequency, stopbandFrequency, passbandRipple, stopbandRipple);
            [z, p, k] = cheby2(n, stopbandRipple, Ws);
            [sosbp, gbp] = zp2sos(z, p, k);
            filteredSignal = filtfilt(sosbp, gbp, data);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StopButton.Enable = "off";
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            cla(app.AxesRaw);
            cla(app.AxesFilter);
            cla(app.AxesEnvelope);
            
            [file, path] = uigetfile('*.wav');
            [data, samplingFrequency] = audioread(strcat(path, file));
            
            [breathsPerMinute, strengthOfBreath] = processData(app, data, samplingFrequency);
            time = size(data, 1)/samplingFrequency;
            app.TimeOfRecordTxt.Text = datestr(seconds(time), 'hh:MM:ss');
            app.BreathsPerMinuteOverallTxt.Text = num2str(round((breathsPerMinute(1)*60)/time));
            
            if (strengthOfBreath >= 0.7)
                app.BreathTypeOverallTxt.Text = 'Hard breath';
            elseif (strengthOfBreath < 0.7 & strengthOfBreath >= 0.5)
                app.BreathTypeOverallTxt.Text = 'Soft breath';
            else
                app.BreathTypeOverallTxt.Text = 'Mild breath';
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.RecordButton.Enable = "off";
            app.LoadButton.Enable = "off";
            app.StopButton.Enable = "on";
            cla(app.AxesRaw);
            cla(app.AxesFilter);
            cla(app.AxesEnvelope);
            audioRecorder(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.RecordButton.Enable = "on";
            app.LoadButton.Enable = "on";
            app.StopButton.Enable = "off";
            
            stop(app.recorder);
            data = getaudiodata(app.recorder);
            fileName = sprintf('test_%s.wav', datestr(now, 'dd-mm-yyyyThh-MM-ss'));
            samplingFrequency = app.samplingFreq;
            audiowrite(fileName, data, samplingFrequency);
            
            time = size(data, 1)/samplingFrequency;
            if time < 62
                chr = 'Signal too';
                chr = [chr newline ' short!'];
                app.BreathsPerMinuteOverallTxt.Text = chr;
            elseif time > 62
                time = time - 60;
                app.BreathsPerMinuteOverallTxt.Text = num2str(round(app.overallBPM/time));
                strengthOfBreath = app.overallSOB/time;
                
                if (strengthOfBreath >= 0.7)
                    app.BreathTypeOverallTxt.Text = 'Hard breath';
                elseif (strengthOfBreath < 0.7 && strengthOfBreath >= 0.5)
                    app.BreathTypeOverallTxt.Text = 'Soft breath';
                else
                    app.BreathTypeOverallTxt.Text = 'Mild breath';
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BreathParams.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {497, 497};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {139, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BreathParams = uifigure('Visible', 'off');
            app.BreathParams.Name = 'UI Figure';
            app.BreathParams.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BreathParams);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RecordButton = uibutton(app.LeftPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TimeOfRecordLabel = uilabel(app.LeftPanel);
            app.TimeOfRecordLabel.Text = {'Time of record'; ''};
            app.BreathsPerMinuteTempLabel = uilabel(app.LeftPanel);
            app.BreathsPerMinuteTempLabel.Text = {'Breaths per minute '; '(temporary)'; ''};
            app.TimeOfRecordTxt = uilabel(app.LeftPanel);
            app.TimeOfRecordTxt.Text = '';
            app.BreathsPerMinuteTempTxt = uilabel(app.LeftPanel);
            app.BreathsPerMinuteTempTxt.Text = '';
            app.BreathsPerMinuteOverallLabel = uilabel(app.LeftPanel);
            app.BreathsPerMinuteOverallLabel.Text = {'Breaths per minute '; '(overall)'; ''};
            app.BreathsPerMinuteOverallTxt = uilabel(app.LeftPanel);
            app.BreathsPerMinuteOverallTxt.Text = '';
            app.BreathTypeLastLabel = uilabel(app.LeftPanel);
            app.BreathTypeLastLabel.Text = 'Breath type (last)';
            app.BreathTypeLastTxt = uilabel(app.LeftPanel);
            app.BreathTypeLastTxt.Text = '';
            app.BreathTypeOverallLabel = uilabel(app.LeftPanel);
            app.BreathTypeOverallLabel.Text = 'Breath type (overall)';
            app.BreathTypeOverallTxt = uilabel(app.LeftPanel);
            app.BreathTypeOverallTxt.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.AxesFilter = uiaxes(app.RightPanel);
            title(app.AxesFilter, 'Envelope of the signal')
            xlabel(app.AxesFilter, 'Time, s')
            ylabel(app.AxesFilter, 'Amplitude')
            app.AxesRaw = uiaxes(app.RightPanel);
            title(app.AxesRaw, 'Raw signal (downsampled)')
            xlabel(app.AxesRaw, 'Time, s')
            ylabel(app.AxesRaw, 'Amplitude')
            app.AxesEnvelope = uiaxes(app.RightPanel);
            title(app.AxesEnvelope, 'Detected breath events')
            xlabel(app.AxesEnvelope, 'Time, s')
            ylabel(app.AxesEnvelope, 'Amplitude')
            app.BreathAll = uilabel(app.RightPanel);
            app.BreathAll.Text = '';
            % Show the figure after all components are created
            app.BreathParams.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = breathFreqGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BreathParams)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BreathParams)
        end
    end
end